classdef SEAPF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SEAPFUIFigure                   matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool2                       matlab.ui.container.toolbar.PushTool
        Bttn_Analisis                   matlab.ui.control.Button
        HTML                            matlab.ui.control.HTML
        ActividadLampLabel              matlab.ui.control.Label
        ActividadLamp                   matlab.ui.control.Lamp
        Panelinformativo                matlab.ui.container.Panel
        MostrarinformacindecolorButton  matlab.ui.control.Button
        Button_NuevoAnalisis            matlab.ui.control.Button
        Button_ReportAnalisis           matlab.ui.control.Button
        ResultadosdeanlisisPanel        matlab.ui.container.Panel
        lblInfo                         matlab.ui.control.Label
        lblAntocianinas                 matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SimilitudesconotrasmuestrasTab  matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        UIAxes_local5                   matlab.ui.control.UIAxes
        UIAxes_local4                   matlab.ui.control.UIAxes
        UIAxes_local3                   matlab.ui.control.UIAxes
        UIAxes_local2                   matlab.ui.control.UIAxes
        UIAxes_local1                   matlab.ui.control.UIAxes
        Panel_Muestras_ROI              matlab.ui.container.Panel
        DropDown_NumPop                 matlab.ui.control.DropDown
        NmPoblacionessimilaresDropDownLabel  matlab.ui.control.Label
        CargarmuestraButton             matlab.ui.control.Button
        UIAxes_unknowImg                matlab.ui.control.UIAxes
        UIAxes_ROIImg                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Definición de variables globales
        estimacion = 0
        dirImages = 'DB/IMAGES';
        unknowImage = [];
        unknowImageMask = '';
        SegRoiView  = [];
        distance = '';
        ponderar = '';
        training_ab = [];
        trainingClase = [];
        trainingsuperClase = [];
        antocianinas = [];
        %% Carga del modelo y bases de datos.
        CNNModel;
        clase = '';
        superclase = '';
        antocia = '';
        claseAsignada = '';
        parametros = '';
        ch = '';
        ruta = '';
        LabColor = [];
    end
    
    methods (Access = public)
        
        function [BW, Mask] = Segmetation(app, I_rgb)
            I = rgb2lab(I_rgb);
            % Define el umbral para el canal 1 
            channel1Min = 0;
            channel1Max = 89.648;
            %  Define el umbral para el canal 2 
            channel2Min = -6.330;
            channel2Max = 129.020;
            % Define el umbral para el canal 3
            channel3Min = -128.935;
            channel3Max = 129.074;
            % Crea la mascara basada en el umbral indicado en cada canal
            Mask = (I(:,:,1) >= channel1Min ) & (I(:,:,1) <= channel1Max) & ...
                (I(:,:,2) >= channel2Min ) & (I(:,:,2) <= channel2Max) & ...
                (I(:,:,3) >= channel3Min ) & (I(:,:,3) <= channel3Max);
            Mask = CleanImpurities(app, Mask); %Elimina pixeles
            Mask =  imfill(Mask, "holes"); %procesamiento morfológico
            se = offsetstrel('ball', 3, 1);
            Mask = imerode(uint8(Mask), se);
            Mask = bwperim(Mask);
            Mask =  imfill(Mask, "holes");
            Mask = uint8(Mask);
            [BW] = colorearFondo(app, I_rgb, Mask);            
        end
        
        function Mask = CleanImpurities(~, Impurities)
            [ML, ~]=bwlabel(Impurities);         % Etiquetar granos de frijol conectados
            propied= regionprops(ML);      % Calcular propiedades de los objetos de la imagen
            s=find([propied.Area] < 3000); % grupos menores a 100 px
            for i=1:size(s,2)              % eliminaci�n de pixeles
                index = ML == s(i);
                Impurities(index) = 0;
            end
            
            s=find([propied.Area] > 70000); % grupos menores a 100 px
            for i=1:size(s,2)              % eliminaci�n de pixeles
                index = ML == s(i);
                Impurities(index) = 0;
            end
            [ML, ~]=bwlabel(Impurities);         % Etiquetar granos de frijol conectados
            propied= regionprops(ML);
            for i=1 :length(propied)
                pos4 = propied(i).BoundingBox(4);
                pos3 = propied(i).BoundingBox(3);
                if pos4>272 && pos3<60
                    s=find([propied.Area] == propied(i).Area);
                    index = ML == s(1);
                    Impurities(index) = 0;
                end
            end
            Mask = Impurities;
        end
        function [segmento] = colorearFondo(app, I, Mask)
            % Función que permite colorear el fondo segmentado
            I_rgbF = uint8(I / 256);
            borde = uint8(Mask);
            segmento=(I_rgbF);  % Obtenci?n de la imagen para dejar la parte segmentada
            segmento(:,:,1)= segmento(:,:,1).*borde;
            segmento(:,:,2)= segmento(:,:,2).*borde;
            segmento(:,:,3)= segmento(:,:,3).*borde;
            segmento(segmento==0)=255;
         
        end
        function fixAxes(app, axisHandle, etiqueta, costo, anto, superClase)
            % Función que permite mostrar la información de poblaciones
            % similares usando el componente UIAxes
            directorio = strcat(app.dirImages, '/',etiqueta,'.tif');
            axisHandle.XLabel.String = {superClase, '  Antocianinas ', num2str(anto)};
            axisHandle.YLabel.String = '';
            axisHandle.Title.String = {etiqueta, ...
                strcat(' % ',num2str(costo))};
            axisHandle.GridLineStyle = 'none';
            similar = imread(directorio);
            directorioMask = strcat(app.dirImages, '/Masks/',etiqueta,'.mat');
            MaskFile =  load(directorioMask);
            Mask = MaskFile.Mask;
            Mask = uint8(~Mask);
           [segmento] = colorearFondo(app, similar, Mask);
            J = imresize(segmento, 50/100);
            imshow(J,'Parent',axisHandle);
        end
        
        function [predicted_class, score, cost] = knn_Matlab(~, train, unknow, lbltraining, dist, K, DW )
            disp(['K = ',int2str(K)]);      % log sobre K procesamiento
            Model = fitcknn(train,lbltraining,'NumNeighbors',K, 'Distance', dist, 'DistanceWeight', DW);
            [predicted_class,score,cost] = predict(Model,unknow);
        end
        
        function [cie_ab, cie_la, cie_lb] = Img2Hist2DABLALB(~, ILab, Mask)
            % Img2Hist2D genera matriz de distribución de probabilidad
            % conjunta para representar la inforción colorimétrica de las
            % poblaciones de frijol.
            % Retorna tres histogramas
            %% Obtenci�n de indices
            [global_rows, global_cols, ~] = size(ILab);
            MaskV=reshape(Mask,global_rows * global_cols, 1);
            indices = MaskV == 0;
            data_raw=reshape(ILab, global_rows*global_cols,3);
            datosLAB=data_raw(indices,:);
            [rows,~] = size(datosLAB);
            datosLAB(:,1) = datosLAB (:,1) / 100;     % l* normalizaci?n
            datosLAB(:,1) = datosLAB(:,1) * 255;     % l* escalado a
            datosLAB(:,1) = datosLAB(:,1) + 1;       % desplazamiento en l, rango de 1/256
            datosLAB(:,2:3) = datosLAB(:,2:3) + 129; % Escala el rango de -128/127 a 1/256 a* b*
            datosLAB = round(datosLAB);
            cie_ab=zeros(256,256);
            tic
            for i=1:rows
                ind = datosLAB(i,:);
                cie_ab(ind(2),ind(3)) = cie_ab(ind(2), ind(3)) + 1;
            end
            toc
            cie_ab=cie_ab/rows;
            disp([datestr(datetime), ' Histograma 2D de A*B* creado']);
            
            cie_la=zeros(256,256);
            tic
            for i=1:rows
                ind = datosLAB(i,:);
                cie_la(ind(1),ind(2)) = cie_la(ind(1), ind(2)) + 1;
            end
            toc
            cie_la=cie_la/rows;
            disp([datestr(datetime), ' Histograma 2D de L*A* creado']);
            
            cie_lb=zeros(256,256);
            tic
            for i=1:rows
                ind = datosLAB(i,:);
                cie_lb(ind(1),ind(3)) = cie_lb(ind(1), ind(3)) + 1;
            end
            toc
            cie_lb=cie_lb/rows;
            disp([datestr(datetime), ' Histograma 2D de L*B* creado']);
            % H2ABLALB = cat(3, cie_ab, cie_la, cie_lb);
            
        end
        function out = atan2Deg(~, inY,inX)
            %función que permite calcular el ángulo de matiz
  	       out = atan2(inY,inX).*180./pi;
           out = out+(out<0).*360;
        end
        
        function [L, a, b, c1, h1] = CromaHueRoi(app, ILab, Mask)
            %función para calcular la cromaticidad de las regiones de
            %interés
            %% Obtenci�n de indices
            [global_rows, global_cols, ~] = size(ILab);
            MaskV=reshape(Mask,global_rows * global_cols, 1);
            indices = MaskV == 0;
            data_raw=reshape(ILab, global_rows*global_cols,3);
            lab1=data_raw(indices,:);
            L = lab1(:,1);
            a = lab1(:,2);
            b = lab1(:,3);
            c1 = sqrt(a.^2+b.^2);
            h1 = atan2Deg(app, b,a);
        end
        
        function f = plot_Lab(app, Lab, createnewfig)
            % Función para mostrar ventana con gráficas de distribución de
            % color
            if createnewfig
                f = figure;
            else
                f = gcf;
            end
            set(f,'PaperUnits','centimeters');
            set(f,'name','Información de color');
            set(0,'units','pixels') ;
            screen = get(0,'screensize');
            set(f,'Position',[200, screen(4)-screen(4)/3-200, screen(3)/2, screen(4)/3]);
            
            cform = makecform('lab2srgb','AdaptedWhitePoint',whitepoint('D65'));
            RGB = applycform(Lab',cform);
            
  
            min_Lab = min(Lab,[],2);
            max_Lab = max(Lab,[],2);
            
            if min_Lab(1) > 0
                min_Lab(1) = 0;
            end
            if min_Lab(2) > -127
                min_Lab(2) = -127;
            end
            if min_Lab(3) > -127
                min_Lab(3) = -127;
            end
            
            if max_Lab(1) < 100
                max_Lab(1) = 100;
            end
            if max_Lab(2) < 128
                max_Lab(2) = 128;
            end
            if max_Lab(3) < 128
                max_Lab(3) = 128;
            end

            subplot(2,2,[1,3]);
            sca1 = scatter3(Lab(3,:),Lab(2,:),Lab(1,:), 5,RGB, "filled");
            sca1.DataTipTemplate.DataTipRows(1).Label = 'b*';
            sca1.DataTipTemplate.DataTipRows(2).Label = 'a*'; 
            sca1.DataTipTemplate.DataTipRows(3).Label = 'L*'; 
            xlabel('b*'),ylabel('a*'), zlabel('L*');
            title('Distribución de color' );
            grid on
            axis([min_Lab(3) max_Lab(3) min_Lab(2) max_Lab(2) min_Lab(1) max_Lab(1)]);grid on;hold on;
            % draw black lines indicating axis
            linewidth = 1;
            line([min_Lab(3) max_Lab(3)],[0 0],[0 0],'color',[0 0 0],'lineWidth',linewidth);     % Axis L* == a* == 0
            line([0 0],[min_Lab(2) max_Lab(2)],[0 0],'color',[0 0 0],'lineWidth',linewidth);     % Axis L* == b* == 0
            line([0 0],[0 0],[min_Lab(1) max_Lab(1)],'color',[0 0 0],'lineWidth',linewidth);     % Axis a* == b* == 0
            
            subplot(2,2,2);  
            c1 = sqrt(Lab(2,:).^2+Lab(3,:).^2);
            h1 = atan2Deg(app, Lab(3,:),Lab(2,:));
            h1 = deg2rad(h1);            
            s1=polarscatter(h1, c1,5, RGB, 'filled');
            s1.DataTipTemplate.DataTipRows(1).Label = 'hº';
            s1.DataTipTemplate.DataTipRows(2).Label = 'cº'; 
            title('Cromaticidad y hº:Ángulo de matiz');
            %polarplot(R, mean(c1), '--*', 'MarkerFaceColor', );
            subplot(2,2,4);
            s = polarscatter(mean(h1), mean(c1),50, mean(RGB), 'filled');
            s.DataTipTemplate.DataTipRows(1).Label = 'hº';
            s.DataTipTemplate.DataTipRows(2).Label = 'cº'; 
            title('Cromaticidad y hº:Ángulo de matiz');
            parametrosLab = strcat('Parámetros L*=', num2str(mean(Lab(1,:))),'  a*=', num2str(mean(Lab(2,:))),'  b*=', num2str(mean(Lab(3,:))));            
            subtitle(parametrosLab,"HorizontalAlignment","center")
            pax = gca;
            pax.GridColor = 'red';
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Durante la carga de la aplicación es necesario cargar los
            %modelos de aprendizaje supervisado para realizar las
            %estimaciones de antocianinas
            app.Panelinformativo.Visible = 'off';
            app.HTML.HTMLSource = fullfile(pwd,'page.html');
            
            directorio = strcat('DB/');
            db = load(strcat(directorio,'dbTool.mat'),'-mat');
            
            for i = 1:size(db.dataset,2)
                data = db.dataset(i);
                cie_ab_e = data.cie_ab_e;
                ab_e = reshape(cie_ab_e, size(cie_ab_e,1)*size(cie_ab_e,2),1);
                app.training_ab = [app.training_ab;ab_e'];
                app.trainingClase = [app.trainingClase;data.clase];
                app.trainingsuperClase = [app.trainingsuperClase;{data.superClase}];
                app.antocianinas = [app.antocianinas;mean(data.valantocianinas)];
            end
            app.distance = 'cityblock';
            app.ponderar = 'squaredinverse';
            % Carga de la informacion de la CNN
            dbCNN = load(strcat(directorio,'CNN.mat'),'-mat');
            app.CNNModel = dbCNN.net;
        end

        % Button pushed function: CargarmuestraButton
        function CargarmuestraButtonPushed(app, event)
            [file,path]=uigetfile({'*.tif',...
                'Imágenes permitidas (*.tif)';
                '*.tif','Imágenes (*.tif)'},'Selecciona una imagen');
            if ~isequal(file,0)
                app.ActividadLamp.Color = [0,1,0];
                app.ActividadLampLabel.Text = 'Procesando...';
                f = waitbar(0, 'Iniciando carga', 'WindowStyle',"modal");
                img=imread(fullfile(path,file));
                app.ruta = fullfile(path,file);
                waitbar(.33,f,'Archivo cargado');
                app.ActividadLampLabel.Text = 'Archivo cargardo...';
                app.unknowImage = img;
                waitbar(.50,f,'Obteniendo información');
                app.ActividadLampLabel.Text = 'Obteniendo información...';
                imshow(img,'Parent',app.UIAxes_unknowImg);
                waitbar(.80,f,'Obteniendo regiones de interés');
                app.ActividadLampLabel.Text = 'Obteniendo regiones de interés...';
                [BW, mask] = Segmetation(app, img);
                app.unknowImageMask = mask;
                app.SegRoiView = BW;
                
                imshow(BW,'Parent',app.UIAxes_ROIImg);
                waitbar(1,f,'Carga completa');
                app.ActividadLampLabel.Text = 'Carga completa';
                close(f)
                app.ActividadLamp.Color = [1,0,0];
                app.ActividadLampLabel.Text = 'Actividad';
                %close(d);
                %delete(fig);
            end
        end

        % Button pushed function: Bttn_Analisis
        function Bttn_AnalisisButtonPushed(app, event)
            % Es esta sección de código esta incuida toda la funcionalidad
            % necesaria para la realización del analisis de estimación de
            % antocianinas en una nueva muestra.
            app.ActividadLamp.Color = [0,1,0];
            app.ActividadLampLabel.Text = 'RGB a CIE 1976 L*a*b*';
            pause(1);
            I = imread(app.ruta); % Leer la imagen de la variable ruta
            % Funcionalidad relacionada con la calibración del color, la
            % transformación de sRGB a CIE Lab
            perfil = iccread('DSC00005.icc');
            perfil.Header.ColorSpace
            perfil.Header.ConnectionSpace
            C = makecform('clut', perfil, 'AToB1');
            
            I_Lab = applycform(I, C);
            aDouble = double(I_Lab); 
            Lab= [];
            % 16 bits
            Lab(:,:,1) = aDouble(:,:,1)*100/65280; % L
            Lab(:,:,2) = aDouble(:,:,2)/256 - 128; % a
            Lab(:,:,3) = aDouble(:,:,3)/256 - 128 ; % b
            Mask = ~app.unknowImageMask;
            [cie_ab, cie_la, cie_lb] = Img2Hist2DABLALB(app, Lab, Mask);
            element = cat(3, cie_ab, cie_la, cie_lb);
            app.estimacion = predict(app.CNNModel, element);
            app.lblAntocianinas.Text = ['La muestra tiene concentración de ', num2str(app.estimacion), ' mg Cyn-3-Glu g-1 b.s.'];
            drawnow;
            app.ActividadLampLabel.Text = 'Clasificación por variedad';
            unknow = reshape(cie_ab, size(cie_ab,1)*size(cie_ab,2),1)';
            
            k=str2double(app.DropDown_NumPop.Value);
            [predicted_class, score, cost] = knn_Matlab...
                (app, app.training_ab, unknow, app.trainingClase, app.distance, k, app.ponderar);
            if length(score)>1
                [~,ILbl] = sort(score, 'descend');
            else
                ILbl = score;
            end
            tmpArray = string(app.trainingClase);
            etiquetas = tmpArray(ILbl);
            etiquetas = etiquetas(1:k);
            anto = app.antocianinas(ILbl);
            anto = anto(1:k);
            tmpArraysp = string(app.trainingsuperClase);
            etiquetassc = tmpArraysp(ILbl);
            
            app.clase = etiquetas;
            app.superclase = etiquetassc(1:k);
            app.antocia = anto;
            
            costo = (cost - 1)*-1;
            costo = costo(ILbl);
            costo = costo(1:k);
            costo = costo*100;
            if(k>0)
                fixAxes(app, app.UIAxes_local1, etiquetas{1}, costo(1), anto(1), etiquetassc{1})
            end
            if(k>1)
                fixAxes(app, app.UIAxes_local2, etiquetas{2}, costo(2), anto(2), etiquetassc{2})
            end
            if(k>2)
                fixAxes(app, app.UIAxes_local3, etiquetas{3}, costo(3), anto(3), etiquetassc{3})
            end
            if(k>3)
                fixAxes(app, app.UIAxes_local4, etiquetas{4}, costo(4), anto(4), etiquetassc{4})
            end
            if(k>4)
                fixAxes(app, app.UIAxes_local5, etiquetas{5}, costo(5), anto(5), etiquetassc{5})
            end
            %lblInfo
            app.ActividadLampLabel.Text = 'Clasificación por color del grano';
            [predicted_supclass, score, cost] = knn_Matlab...
                (app, app.training_ab, unknow, app.trainingsuperClase, app.distance, k, app.ponderar);
            val = sum(score > 0);
            if val>1
                [~,ILbl] = sort(score, 'descend');
                training = string(unique(app.trainingsuperClase));
                etiquetas = training(ILbl);
                etiquetas = etiquetas(1:k);
            else
                etiquetas = predicted_supclass;
            end
            [L, a, b, c1, h1] = CromaHueRoi(app, Lab, Mask);
            app.lblInfo.Text = [etiquetas; ...
                strcat('L* :',num2str(mean(L)), {'  '}, ...
                'a* :',num2str(mean(a)), {'  '}, ...
                'b* :',num2str(mean(b))); ...
                strcat('C* :', num2str(mean(c1)), {'  '}, ...
                'H* :', num2str(mean(h1)))];
            app.parametros = strcat('L* :',num2str(mean(L)), {'  '}, ...
                'a* :',num2str(mean(a)), {'  '}, ...
                'b* :',num2str(mean(b)));
            app.ch = strcat('C* :', num2str(mean(c1)), {'  '}, ...
                'H* :', num2str(mean(h1)));
            app.claseAsignada = etiquetas;
            app.LabColor = [L'; a'; b']; 

            app.ActividadLamp.Color = [1,0,0];
            app.ActividadLampLabel.Text = 'Proceso finalizado';
            app.Panelinformativo.Visible = 'on';
            pause(1);
        end

        % Button pushed function: Button_NuevoAnalisis
        function Button_NuevoAnalisisPushed(app, event)

    % ocultar la actual instancia
    app.SEAPFUIFigure.Visible = 'off';
    % Abrir una segunda instancia de la aplicación para un nuevo análisis
    Herramienta();
    % Eliminar la anterior instancia
    close(app.SEAPFUIFigure)
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            makeDOMCompilable(); 
            import mlreportgen.dom.*
            import mlreportgen.report.*
            
            % Agregar contenedor al reporte
            rpt = Report('variedad','pdf');
            titlepg = TitlePage;
            titlepg.Title = 'Poblaciones nativas de frijol';
            titlepg.Author = 'Herramienta';
            add(rpt,titlepg);
            add(rpt,TableOfContents);
  
            chap = Chapter('Clase');
            add(chap,'Here is the plane:');
            add(chap, 'POB-02: Blancos');
            add(chap, 'POB-02: Blancos');
            add(chap, 'POB-02: Blancos');
            add(chap, 'POB-02: Blancos');
            add(rpt,chap);
            
            close(rpt);
            rptview(rpt);
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            % El siguiente código permite generar un concentrado de las
            % poblaciones usadas en el modelo de estimación de antocianinas            
            makeDOMCompilable(); 
            import mlreportgen.dom.*;
            import mlreportgen.report.*
            d = Document('Herramientareporte', 'docx');
            open(d);
            currentLayout = d.CurrentPageLayout;
            docxheader = DOCXPageHeader();
            p = Paragraph('Reporte poblaciones nativas de frijol');
            p.Style = [p.Style, {HAlign('center'), Bold(true), FontSize('12pt')}];
            append(docxheader, p);
            currentLayout.PageHeaders = docxheader;
            dataTableStyle = {Border('solid'), ColSep('solid'), RowSep('solid'), Width('100%')...
                OuterMargin('0pt', '0pt', '0pt', '0pt')};
            dataHeader = {'Núm', 'Nombre', 'Descripción por color', 'Antocianinas'};
            directorio = strcat('DB/');
            db = load(strcat(directorio,'dbTool.mat'),'-mat');
            
            dataBody = cell(length(db.dataset),4);
            for i = 1:length(db.dataset)
                data = db.dataset(i);
                dataBody(i,:) = {num2str(i), data.clase, data.superClase, num2str(data.valantocianinas)};
            end
            
           
            dataTable = FormalTable(dataHeader, dataBody);
            
            dataTable.Header.Style = [dataTable.Header.Style {Bold}];
            dataTable.Style = [dataTable.Style dataTableStyle];
            append(d, dataTable);
            currentLayout.PageMargins.Top = '1in';
            currentLayout.PageMargins.Header = '0.75in';
            docxfooter = DOCXPageFooter();
            append(docxfooter, HorizontalRule());
            imgStyle = {ScaleToFit(true), HAlign('right'), Height('0.30in')};
            img = Image('logo.png');
            img.Style = imgStyle;
            append(docxfooter, img);
            currentLayout.PageFooters = docxfooter;
            currentLayout.PageMargins.Bottom = '1in';
            currentLayout.PageMargins.Footer = '0.5in';
            currentLayout.PageMargins.Gutter = '0.25in';
            currentLayout.PageMargins.Left = '0.5in';
            currentLayout.PageMargins.Right = '0.5in';
            close(d);
            rptview(d.OutputPath);
        end

        % Button pushed function: Button_ReportAnalisis
        function Button_ReportAnalisisPushed(app, event)
            % La siguiente función permite crear el reporte del análisis de
            % estimación de antocianinas.
            makeDOMCompilable(); 
            import mlreportgen.dom.*
            import mlreportgen.report.*
            rpt = Report('ReporteAnalisis', 'pdf'); % crea un mlreportgen.report.Report object
            titlepg = TitlePage;
            titlepg.Title = 'Sistema para la Estimación de Antocianinas en Poblaciones de Frijol';
            titlepg.Author = 'Universidad Veracruzana';
            add(rpt,titlepg);
            h = Heading(1, 'Resultados de estimación');
            h.Style = [h.Style {HAlign('center')}];
            append(rpt, h);
            
            im = Image(which('logo.png'));
            im.Width = '0.75in';
            im.Height = '0.75in';
            % contruye el encabezado
            header = PDFPageHeader();
            append(header, im);
            % obtiene la capa de la página del reporte y asigna el
            % encabezado
            layout = getReportLayout(rpt);
            layout.PageHeaders = header;
            layout.PageMargins.Header = '1in';
            layout.PageMargins.Top = '0.5in';            
            p = Paragraph('Muestra analizada');
            add(rpt, p);
            % Para reducción del tamaño de la imagen
            I = uint8(app.unknowImage / 256);
            val = 50/100;
            J = imresize(I, val);
            
            imagesc(J)
            figure = Figure();
            peaks20 = Image(getSnapshotImage(figure,rpt));
            peaks20.Width = '3in';
            peaks20.Height = [];
            delete(gcf);
            
            imagesc(app.SegRoiView)
            figure = Figure();
            peaks40 = Image(getSnapshotImage(figure,rpt));
            peaks40.Width = '3in';
            peaks40.Height = [];
            delete(gcf);            
            
            lot = Table({peaks20, ' ', peaks40});
            lot.entry(1,1).Style = {Width('3.2in'), Height('3in')};
            lot.entry(1,2).Style = {Width('.2in'), Height('3in')};
            lot.entry(1,3).Style = {Width('3.2in'), Height('3in')};
            lot.Style = {ResizeToFitContents(false), Width('100%')};
            add(rpt, lot);
            
            p1 = Paragraph("La concentración de antocianinas de la muestra es: " + string(app.estimacion));
            add(rpt, p1);
            
            p1 = Paragraph(app.claseAsignada{1});
            add(rpt, p1);
            
            p1 = Paragraph(app.parametros{1});
            add(rpt, p1);
            
            p1 = Paragraph(app.ch{1});
            add(rpt, p1);
            
            h = Heading(1, 'Poblaciones nativas de frijol con las que comparte similitud');
            h.Style = [h.Style {HAlign('center')}];
            append(rpt, h);
            
            
            dataBody = cell(length(app.clase)*2,3);
            linea = 1;
            for i=1:length(app.clase)
                descAnto = strcat(num2str(app.antocia(i)), ' mg Cyn-3-Glu g-1 b.s.');
                strextra = {app.clase{i}; app.superclase{i}; descAnto};
                directorio = strcat(app.dirImages, '/',app.clase{i},'.tif');
                im_pob = imread(directorio);
                directorioMask = strcat(app.dirImages, '/Masks/',app.clase{i},'.mat');
                MaskFile =  load(directorioMask);
                Mask = MaskFile.Mask;
                Mask = uint8(~Mask);
               [segmento] = colorearFondo(app, im_pob, Mask);                                                
                segmento = imresize(segmento, .3);
                imagesc(segmento)
                figure = Figure();
                peaksE = Image(getSnapshotImage(figure,rpt));
                peaksE.Width = '2.8in';
                peaksE.Height = [];
                delete(gcf);
                if mod(i, 2)==1
                    dataBody{linea, 1} = strextra;
                    dataBody{linea+1, 1} = peaksE;
                else % Par
                    dataBody{linea, 3} = strextra;
                    dataBody{linea+1, 3} = peaksE;
                    linea = linea + 2;
                end
            end
            
            lo_table = Table(dataBody);
            lot.entry(1,1).Style = {Width('3.2in'), Height('3in')};
            lot.entry(1,2).Style = {Width('.2in'), Height('3in')};
            lot.entry(1,3).Style = {Width('3.2in'), Height('3in')};
            add(rpt, lo_table);
            
            % Close and view report
            close(rpt);
            rptview(rpt)
            
        end

        % Button pushed function: MostrarinformacindecolorButton
        function MostrarinformacindecolorButtonPushed(app, event)
            plot_Lab(app, app.LabColor, 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SEAPFUIFigure = uifigure('Visible', 'off');
            app.SEAPFUIFigure.Name = 'SEAPF';
            app.SEAPFUIFigure.Icon = 'logo.png';
            app.Toolbar = uitoolbar(app.SEAPFUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Reporte: Variedades registradas en el sistema'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Separator = 'on';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Reporte: Concentrado de poblaciones usadas por el sistema'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Separator = 'on';
            app.Panel_Muestras_ROI = uipanel(app.SEAPFUIFigure);
            app.Panel_Muestras_ROI.Title = 'Muestra y regiones de interés';
            app.UIAxes_ROIImg = uiaxes(app.Panel_Muestras_ROI);
            app.UIAxes_ROIImg.XTick = [];
            app.UIAxes_ROIImg.YTick = [];
            app.UIAxes_unknowImg = uiaxes(app.Panel_Muestras_ROI);
            app.UIAxes_unknowImg.XTick = [];
            app.UIAxes_unknowImg.YTick = [];
            app.CargarmuestraButton = uibutton(app.Panel_Muestras_ROI, 'push');
            app.CargarmuestraButton.ButtonPushedFcn = createCallbackFcn(app, @CargarmuestraButtonPushed, true);
            app.CargarmuestraButton.Text = 'Cargar muestra';
            app.NmPoblacionessimilaresDropDownLabel = uilabel(app.Panel_Muestras_ROI);
            app.NmPoblacionessimilaresDropDownLabel.Text = 'Núm. Poblaciones similares';
            app.DropDown_NumPop = uidropdown(app.Panel_Muestras_ROI);
            app.DropDown_NumPop.Items = {'1', '3', '5'};
            app.DropDown_NumPop.Value = '5';
            app.ResultadosdeanlisisPanel = uipanel(app.SEAPFUIFigure);
            app.ResultadosdeanlisisPanel.Title = 'Resultados de análisis';
            app.TabGroup = uitabgroup(app.ResultadosdeanlisisPanel);
            app.SimilitudesconotrasmuestrasTab = uitab(app.TabGroup);
            app.SimilitudesconotrasmuestrasTab.Title = 'Similitudes con otras muestras';
            app.GridLayout = uigridlayout(app.SimilitudesconotrasmuestrasTab);
            app.UIAxes_local1 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_local1, 'X')
            ylabel(app.UIAxes_local1, 'Y')
            app.UIAxes_local2 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_local2, 'X')
            ylabel(app.UIAxes_local2, 'Y')
            app.UIAxes_local3 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_local3, 'X')
            ylabel(app.UIAxes_local3, 'Y')
            app.UIAxes_local4 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_local4, 'X')
            ylabel(app.UIAxes_local4, 'Y')
            app.UIAxes_local5 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes_local5, 'X')
            ylabel(app.UIAxes_local5, 'Y')
            app.lblAntocianinas = uilabel(app.ResultadosdeanlisisPanel);
            app.lblAntocianinas.Text = '';
            app.lblInfo = uilabel(app.ResultadosdeanlisisPanel);
            app.lblInfo.Text = '';
            app.Panelinformativo = uipanel(app.SEAPFUIFigure);
            app.Panelinformativo.Title = 'Panel informativo';
            app.Button_ReportAnalisis = uibutton(app.Panelinformativo, 'push');
            app.Button_ReportAnalisis.ButtonPushedFcn = createCallbackFcn(app, @Button_ReportAnalisisPushed, true);
            app.Button_ReportAnalisis.Text = 'Generar reporte de análisis';
            app.Button_NuevoAnalisis = uibutton(app.Panelinformativo, 'push');
            app.Button_NuevoAnalisis.ButtonPushedFcn = createCallbackFcn(app, @Button_NuevoAnalisisPushed, true);
            app.Button_NuevoAnalisis.Text = 'Realizar nuevo análisis';
            app.MostrarinformacindecolorButton = uibutton(app.Panelinformativo, 'push');
            app.MostrarinformacindecolorButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarinformacindecolorButtonPushed, true);
            app.MostrarinformacindecolorButton.Text = 'Mostrar información de color';
            app.ActividadLamp = uilamp(app.SEAPFUIFigure);
            app.ActividadLamp.Tooltip = {'Actividad en el sistema'};
            app.ActividadLampLabel = uilabel(app.SEAPFUIFigure);
            app.ActividadLampLabel.Text = 'Actividad';
            app.HTML = uihtml(app.SEAPFUIFigure);
            app.Bttn_Analisis = uibutton(app.SEAPFUIFigure, 'push');
            app.Bttn_Analisis.ButtonPushedFcn = createCallbackFcn(app, @Bttn_AnalisisButtonPushed, true);
            app.Bttn_Analisis.Text = 'Analizar la muestra';
            % Show the figure after all components are created
            app.SEAPFUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEAPF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SEAPFUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SEAPFUIFigure)
        end
    end
end